classdef DataCollectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BorderSlider                  matlab.ui.control.Slider
        BorderSliderLabel             matlab.ui.control.Label
        PrevButton                    matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        HeatMapPanel                  matlab.ui.container.Panel
        LocateSensorsButton           matlab.ui.control.Button
        HeatMapButton                 matlab.ui.control.Button
        DatapointDropDown             matlab.ui.control.DropDown
        DatapointDropDownLabel        matlab.ui.control.Label
        LoadButton                    matlab.ui.control.Button
        DatasetDropDown               matlab.ui.control.DropDown
        DatasetDropDownLabel          matlab.ui.control.Label
        DataAnalyzerLabel             matlab.ui.control.Label
        FlipXCheckBox                 matlab.ui.control.CheckBox
        CalibrationSamplesEditField   matlab.ui.control.NumericEditField
        CalibrationSamplesEditFieldLabel  matlab.ui.control.Label
        TXEditField                   matlab.ui.control.NumericEditField
        TXEditFieldLabel              matlab.ui.control.Label
        RXEditField                   matlab.ui.control.NumericEditField
        RXEditFieldLabel              matlab.ui.control.Label
        DescriptionEditField          matlab.ui.control.EditField
        DescriptionEditFieldLabel     matlab.ui.control.Label
        BaudRateEditField             matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel        matlab.ui.control.Label
        SerialPortEditField           matlab.ui.control.EditField
        SerialPortEditFieldLabel      matlab.ui.control.Label
        SampleSizeEditField           matlab.ui.control.NumericEditField
        SampleSizeEditFieldLabel      matlab.ui.control.Label
        SaveLocationEditField         matlab.ui.control.EditField
        SaveLocationEditFieldLabel    matlab.ui.control.Label
        SetpointsandstartwebcamLabel  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        DataPointTrackerLabel         matlab.ui.control.Label
        SkinPatchDataCollectorLabel   matlab.ui.control.Label
        LengthinEditField             matlab.ui.control.NumericEditField
        LengthinEditFieldLabel        matlab.ui.control.Label
        WidthinEditField              matlab.ui.control.NumericEditField
        WidthinEditFieldLabel         matlab.ui.control.Label
        LogDataButton                 matlab.ui.control.Button
        LengthPointsEditField         matlab.ui.control.NumericEditField
        LengthPointsEditFieldLabel    matlab.ui.control.Label
        WidthPointsEditField          matlab.ui.control.NumericEditField
        WidthPointsEditFieldLabel     matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %%%%%%%% Data Logger %%%%%%%%
        webcamObj; % Webcam
        imgObj; %RGB image
        savePath; %File save name
        skinObj; %Serial port connection
        SkinDataSet; %Final data set
        dataRaw; %intermediate data set
        currentPoint; %Point tracking
        lengthMat; %linear vector from 1-total points
        widthMat; %linear vector from 1-total points
        path; %Save Name
        dirLocation;

        %%%%%%% Data Analyzer %%%%%%%%%%
        loadedSet; %Set loaded in by dropdown
        unitsSet;
        currentPointH;
        dataPointIndex;

        %%%%%%% Sensor Locator %%%%%%%%
        locatorApp; %Reference to secondary window
    end
    
    methods (Access = private)
        
        function results = ButtonPress(app)
            
        end
        
        % Reading in the data 
        function dataClean = readSkin(app, skinObj)
            data = readline(skinObj);
            seperatedData = split(data, ',');
            dataClean = zeros(1, length(seperatedData));
            for i = 1:length(dataClean)
                val = str2double(seperatedData(i));
                if ~isnan(val)
                    dataClean(i) = val;
                end
            end
            %If time is included in the measurement
            %skinObj.UserData.Time = str2double(seperatedData(end));
        end

        function logData(app)
            flush(app.skinObj);
            temp = readSkin(app, app.skinObj);
            for i = 1:app.SampleSizeEditField.Value
                app.dataRaw(app.currentPoint).data(i,:) = readSkin(app, app.skinObj) - app.SkinDataSet.noiseProfile;
            end
            app.dataRaw(app.currentPoint).pos = [app.lengthMat(app.currentPoint), app.widthMat(app.currentPoint)];
            app.dataRaw(app.currentPoint).posReal = [((app.lengthMat(app.currentPoint)-1)/(app.LengthPointsEditField.Value-1))*app.LengthinEditField.Value, ...
                                                     ((app.widthMat(app.currentPoint)-1)/(app.WidthPointsEditField.Value-1))*app.WidthinEditField.Value];

            % Saving the image
            app.imgObj = snapshot(app.webcamObj);
            app.dataRaw(app.currentPoint).img = app.imgObj;
        end
    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LogDataButton
        function LogDataButtonPushed(app, event)
    
            app.LogDataButton.Enable ="off";
            %%%%%% Data Logging %%%%%%
            if app.currentPoint < (app.LengthPointsEditField.Value * app.WidthPointsEditField.Value)
                logData(app);

            else
                logData(app);

                % Save data and finish program
                app.SkinDataSet.dataRaw = app.dataRaw;

                %Put the data into averages and usable forms
                spotTrain = 1; %Indicator to find how many data points
                for i = 1:size(app.SkinDataSet.dataRaw,1)
                    for j = 1:size(app.SkinDataSet.dataRaw(1).data,1)
                        app.SkinDataSet.dataRawLin(:, spotTrain) = app.SkinDataSet.dataRaw(i).data(j,:)';
                        app.SkinDataSet.posLin(:, spotTrain) = app.SkinDataSet.dataRaw(i).pos';
                        app.SkinDataSet.posLinReal(:, spotTrain) = app.SkinDataSet.dataRaw(i).posReal';
                        spotTrain = spotTrain+1;
                    end
                    app.SkinDataSet.dataRaw(i).avgData = mean(app.SkinDataSet.dataRaw(i).data, 1);
                    app.SkinDataSet.dataAvgsLin(:, i) = app.SkinDataSet.dataRaw(i).avgData';
                    app.SkinDataSet.posLinAvg(:,i) = app.SkinDataSet.dataRaw(i).pos';
                    app.SkinDataSet.posLinAvgReal(:,i) = app.SkinDataSet.dataRaw(i).posReal';
                end
                set(app.LogDataButton,'Enable','off');
                app.dirLocation = uigetdir;
                SkinDataSet = app.SkinDataSet;
                save(fullfile(app.dirLocation, app.path), 'SkinDataSet');
                closePreview(app.webcamObj);
                delete(app.webcamObj);
                return
                %delete(app);
            end

            %%%%%% Image Display %%%%%%
            % Display screenshot
            closePreview(app.webcamObj)
            %imshow(app.imgObj,'Parent',app.UIAxes, 'Border', 'tight');
            im = image(app.UIAxes, zeros(size(app.imgObj), 'uint8'));
            axis(app.UIAxes, 'image');

            %Find axes limits
            imgLims = size(app.imgObj);
            xMax = imgLims(2);
            yMax = imgLims(1);
            xCenter = round(xMax/2);
            yCenter = round(yMax/2);

            %Defining border size
            borderPercentage = app.BorderSlider.Value;
            outlineRatio = app.LengthinEditField.Value / app.WidthinEditField.Value;
            if app.LengthinEditField.Value/xMax > app.WidthinEditField.Value/yMax
                outlineLengthHalf = xCenter*borderPercentage;
                outlineWidthHalf = outlineLengthHalf/outlineRatio;
            else
                outlineWidthHalf = yCenter*borderPercentage;
                outlineLengthHalf = outlineWidthHalf*outlineRatio;
            end

            %z1 is origin corner
            z1 = [xCenter - outlineLengthHalf, yCenter - outlineWidthHalf];
            z2 = [xCenter + outlineLengthHalf, yCenter - outlineWidthHalf];
            z3 = [xCenter + outlineLengthHalf, yCenter + outlineWidthHalf];
            z4 = [xCenter - outlineLengthHalf, yCenter + outlineWidthHalf];


            %Plotting
            hold(app.UIAxes, 'on');
            preview(app.webcamObj, im);
            plot(app.UIAxes, [z1(1),z2(1)], [z1(2),z2(2)], 'r');
            plot(app.UIAxes, [z2(1),z3(1)], [z2(2),z3(2)], 'r');
            plot(app.UIAxes, [z3(1),z4(1)], [z3(2),z4(2)], 'r');
            plot(app.UIAxes, [z4(1),z1(1)], [z4(2),z1(2)], 'r');
            scatter(app.UIAxes, xCenter, yCenter, 20,'red', 'filled', 'd');
            

            %%%%%% Target Plotting %%%%%%%%%
            if app.currentPoint < (app.LengthPointsEditField.Value * app.WidthPointsEditField.Value)
                app.currentPoint = app.currentPoint + 1 ;
                lengthCoordNew = ((app.lengthMat(app.currentPoint)-1)/(app.LengthPointsEditField.Value-1))*(2*outlineLengthHalf);
                widthCoordNew = ((app.widthMat(app.currentPoint)-1)/(app.WidthPointsEditField.Value-1))*(2*outlineWidthHalf);
                zPoint = [z1(1) + lengthCoordNew, z1(2) + widthCoordNew];
                scatter(app.UIAxes, zPoint(1), zPoint(2), 40,'cyan', 'filled', 'o');
                app.DataPointTrackerLabel.Text = "X: " + app.widthMat(app.currentPoint) + "   Y: " + app.lengthMat(app.currentPoint);
                            
            end

            %%%%%% For Testing %%%%%%%%%%
            %app.DataPointTrackerLabel.Text = "Size: " + string(imgLims);
            
      
            hold(app.UIAxes, 'off');

            %%%%%%%% Panel %%%%%%%%%%%
            currP =1;
            for t = 1:app.TXEditField.Value
                for r = 1:app.RXEditField.Value
                    cData(r,t) = app.dataRaw(end).avgData(currP);
                    currP = currP+1;
                end
            end
            h = heatmap(app.HeatMapPanel, cData, 'GridVisible','off', 'Colormap', hot);

            app.LogDataButton.Enable ="on";

            %set(app.UIAxes,'YDir','normal')

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Setting up webcam
            app.webcamObj = webcam(1);
            app.imgObj = snapshot(app.webcamObj);
            im = image(app.UIAxes, zeros(size(app.imgObj), 'uint8'));
            axis(app.UIAxes, 'image');

            %Find axes limits
            imgLims = size(app.imgObj);
            xMax = imgLims(2);
            yMax = imgLims(1);
            xCenter = round(xMax/2);
            yCenter = round(yMax/2);

            %Defining border size
            borderPercentage = app.BorderSlider.Value;
            outlineRatio = app.LengthinEditField.Value / app.WidthinEditField.Value;
            if app.LengthinEditField.Value/xMax > app.WidthinEditField.Value/yMax
                outlineLengthHalf = xCenter*borderPercentage;
                outlineWidthHalf = outlineLengthHalf/outlineRatio;
            else
                outlineWidthHalf = yCenter*borderPercentage;
                outlineLengthHalf = outlineWidthHalf*outlineRatio;
            end

            z1 = [xCenter - outlineLengthHalf, yCenter - outlineWidthHalf];
            z2 = [xCenter + outlineLengthHalf, yCenter - outlineWidthHalf];
            z3 = [xCenter + outlineLengthHalf, yCenter + outlineWidthHalf];
            z4 = [xCenter - outlineLengthHalf, yCenter + outlineWidthHalf];


            %Plotting
            preview(app.webcamObj, im);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [z1(1),z2(1)], [z1(2),z2(2)], 'r');
            plot(app.UIAxes, [z2(1),z3(1)], [z2(2),z3(2)], 'r');
            plot(app.UIAxes, [z3(1),z4(1)], [z3(2),z4(2)], 'r');
            plot(app.UIAxes, [z4(1),z1(1)], [z4(2),z1(2)], 'r');
            scatter(app.UIAxes, xCenter, yCenter, 20,'red', 'filled', 'd');


            %Disabling button
             set(app.StartButton,'Enable','off')

            %%%%%% Serial Connection %%%%
            %Struct Setup and serial communication

            app.DataPointTrackerLabel.Text = "Connecting to board...";
            app.skinObj = serialport(app.SerialPortEditField.Value,app.BaudRateEditField.Value);
            app.skinObj.UserData = struct("data",[],"Time",[]);
            totalP = app.WidthPointsEditField.Value*app.LengthPointsEditField.Value;
            app.dataRaw = repmat(struct('data',[]), totalP, 1 );
            app.SkinDataSet.desc = app.DescriptionEditField.Value;
            app.SkinDataSet.sensNum = app.TXEditField.Value*app.RXEditField.Value;
            app.SkinDataSet.resWidth = app.WidthPointsEditField.Value;
            app.SkinDataSet.resLength = app.LengthPointsEditField.Value;
            app.SkinDataSet.numRX = app.RXEditField.Value;
            app.SkinDataSet.numTX = app.TXEditField.Value;
            app.SkinDataSet.widthIn = app.WidthinEditField.Value;
            app.SkinDataSet.lengthIn = app.LengthinEditField.Value;
            app.SkinDataSet.noiseRaw = zeros(app.CalibrationSamplesEditField.Value, app.SkinDataSet.sensNum);
            app.SkinDataSet.flippedLength = app.FlipXCheckBox.Value;

            %%%%%% Calibration %%%%%%%%%%
            app.DataPointTrackerLabel.Text = "Calibrating...";
            flush(app.skinObj);
            for i =1:20, readSkin(app, app.skinObj); end
            for i = 1:app.CalibrationSamplesEditField.Value
                app.SkinDataSet.noiseRaw(i,:) = readSkin(app, app.skinObj);
            end
            app.SkinDataSet.noiseProfile = mean(app.SkinDataSet.noiseRaw, 1);

            %Setting up length and width mats
            app.lengthMat = zeros([1,totalP]);
            app.widthMat = zeros([1,totalP]);
            currP = 1;
            app.currentPoint = 1;
            if app.FlipXCheckBox.Value
                for l = 1:app.LengthPointsEditField.Value
                    for w = 1:app.WidthPointsEditField.Value
                        app.widthMat(currP) = w;
                        app.lengthMat(currP) = (app.LengthPointsEditField.Value+1) - l;
                        currP = currP+1;
                    end
                end
            else
                for l = 1:app.LengthPointsEditField.Value
                    for w = 1:app.WidthPointsEditField.Value
                        app.widthMat(currP) = w;
                        app.lengthMat(currP) = l;
                        currP = currP+1;
                    end
                end
            end
            set(app.LogDataButton,'Enable','on');
            app.DataPointTrackerLabel.Text = "X: " + app.widthMat(app.currentPoint) + "   Y: " + app.lengthMat(app.currentPoint);

            %%%%%% Target on Plot %%%%%%%
            %Target spot
            lengthCoordNew = ((app.lengthMat(app.currentPoint)-1)/(app.LengthPointsEditField.Value-1))*(2*outlineLengthHalf);
            widthCoordNew = ((app.widthMat(app.currentPoint)-1)/(app.WidthPointsEditField.Value-1))*(2*outlineWidthHalf);
            zPoint = [z1(1) + lengthCoordNew, z1(2) + widthCoordNew];
            scatter(app.UIAxes, zPoint(1), zPoint(2), 40,'cyan', 'filled', 'o');
            app.DataPointTrackerLabel.Text = "X: " + app.widthMat(app.currentPoint) + "   Y: " + app.lengthMat(app.currentPoint);
            hold(app.UIAxes, 'off');
            

            %%%%%% Save Location %%%%%%%%
            fileName = "SkinPatch" + string(app.WidthinEditField.Value) + "x" + string(app.LengthinEditField.Value) + "_" + string(app.WidthPointsEditField.Value * app.LengthPointsEditField.Value) + "_";
            fileType = ".mat";
            count = 1;
            app.path = fileName + count + fileType;

            fileContents = dir(app.SaveLocationEditField.Value);
            while exist(app.path, "file")
                count = count + 1;
                app.path = fileName+count+fileType;
            end
            app.SaveLocationEditField.Value = app.path;

            %%%%%% For Testing %%%%%%%%%%
            pause;
            

            %set(app.UIAxes,'YDir','normal')

        end

        % Callback function
        function StopButtonPushed(app, event)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            listings = dir("Data");
            itemSet = {};
            itemSet{1} = 'Select Data Set';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.DatasetDropDown.Items = itemSet;

            app.unitsSet = 0; %Heatmap units only need to be set once
            app.currentPointH = 1;
            app.dataPointIndex = 1;
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            if value == ""
                return;
            end
            app.loadedSet = load("Data/" + value);
            for i = 1:size(app.loadedSet.SkinDataSet.dataRaw, 1)
                position = "X: " + string(app.loadedSet.SkinDataSet.dataRaw(i).pos(1)) + ", Y: " + string(app.loadedSet.SkinDataSet.dataRaw(i).pos(2)) + ", #" + i;
                itemSet{i} = convertStringsToChars(position);
            end
            %app.DebuggerLabel.Text = itemSet{2};
            app.DatapointDropDown.Items = itemSet;
            app.LocateSensorsButton.Enable = "on";
            app.HeatMapButton.Enable = "on";
            app.NextButton.Enable = "on";
            app.PrevButton.Enable = "on";

            HeatMapButtonPushed(app, event);
            
        end

        % Clicked callback: DatasetDropDown
        function DatasetDropDownClicked(app, event)

            
        end

        % Button pushed function: HeatMapButton
        function HeatMapButtonPushed(app, event)

            %%%%%%%% Panel %%%%%%%%%%%
            currP = 1;
            for t = 1:app.loadedSet.SkinDataSet.numTX
                for r = 1:app.loadedSet.SkinDataSet.numRX
                    cData(r,t) = app.loadedSet.SkinDataSet.dataRaw(app.dataPointIndex).avgData(currP);
                    currP = currP+1;
                end
            end
            h = heatmap(app.HeatMapPanel, cData, 'GridVisible','off', 'Colormap', hot);

            %%%%%%% Following square box code from Adam Danz %%%%%%%%%%
            % Originial Forum: https://www.mathworks.com/matlabcentral/answers/481666-heatmap-chart-depict-squares-rather-than-rectangles

            if app.unitsSet == 0
                % Temporarily change axis units 
                originalUnits = h.Units;  % save original units (probaly normalized)
                h.Units = 'centimeters';  % any unit that will result in squares
                % Get number of rows & columns
                sz = size(h.ColorData); 
                % Change axis size & position;
                originalPos = h.Position; 
                % make axes square (not the table cells, just the axes)
                h.Position(3:4) = min(h.Position(3:4))*[1,1]; 
                if sz(1)>sz(2)
                    % make the axis size more narrow and re-center
                    h.Position(3) = h.Position(3)*(sz(2)/sz(1)); 
                    h.Position(1) = (originalPos(1)+originalPos(3)/2)-(h.Position(3)/2);
                else
                    % make the axis size shorter and re-center
                    h.Position(4) = h.Position(4)*(sz(1)/sz(2));
                    h.Position(2) = (originalPos(2)+originalPos(4)/2)-(h.Position(4)/2);
                end
                % Return axis to original units
                h.Units = originalUnits; 
                app.unitsSet = 1;
            end

            %%%%%%%% Orginial Location %%%%%%%%%%

            z1 = [0, app.loadedSet.SkinDataSet.widthIn];
            z2 = [app.loadedSet.SkinDataSet.lengthIn, app.loadedSet.SkinDataSet.widthIn];
            z3 = [app.loadedSet.SkinDataSet.lengthIn, 0];
            z4 = [0,0];

%             plot(app.UIAxes2, [z1(1),z2(1)], [z1(2),z2(2)], 'r');
%             hold(app.UIAxes2, 'on');
%             plot(app.UIAxes2, [z2(1),z3(1)], [z2(2),z3(2)], 'r');
%             plot(app.UIAxes2, [z3(1),z4(1)], [z3(2),z4(2)], 'r');
%             plot(app.UIAxes2, [z4(1),z1(1)], [z4(2),z1(2)], 'r');
% 
%             axis(app.UIAxes2, 'equal');
%             hold(app.UIAxes2, 'off');

            imshow(app.loadedSet.SkinDataSet.dataRaw(app.dataPointIndex).img, 'Parent', app.UIAxes2) ;
            
% 
%             %%%%%% Target Plotting %%%%%%%%%
%             if app.currentPointH < (app.loadedSet.SkinDataSet.resLength * app.loadedSet.SkinDataSet.resWidth)
%                 app.currentPointH = app.currentPointH + 1 ;
%                 lengthMat2 = app.loadedSet.SkinDataSet.posLin(1,:);
%                 widthMat2 = app.loadedSet.SkinDataSet.posLin(2,:);
%                 lengthCoordNew = (lengthMat2(app.currentPointH)/(app.loadedSet.SkinDataSet.resLength+1))*(2*outlineLengthHalf);
%                 widthCoordNew = (widthMat2(app.currentPointH)/(app.loadedSet.SkinDataSet.resWidth+1))*(2*outlineWidthHalf);
%                 zPoint = [z1(1) + lengthCoordNew, z1(2) + widthCoordNew];
%                 scatter(app.UIAxes2, zPoint(1), zPoint(2), 40,'cyan', 'filled', 'o');
%                 %app.DataPointTrackerLabel.Text = "X: " + app.widthMat(app.currentPointH) + "   Y: " + app.lengthMat(app.currentPoint);
%                             
%             end
% 
%             %%%%%% For Testing %%%%%%%%%%
%             %app.DataPointTrackerLabel.Text = "Size: " + string(imgLims);
%             
%       
%             hold(app.UIAxes2, 'off');

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Move to next data point entry on heatmap
            if app.dataPointIndex < length(app.DatapointDropDown.Items)
                app.DatapointDropDown.Value =  app.DatapointDropDown.Items{app.dataPointIndex + 1};
                app.dataPointIndex = app.dataPointIndex + 1;
                HeatMapButtonPushed(app, event);
            end
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            % Move to previous data point entry on heatmap
            if app.dataPointIndex > 1
                app.DatapointDropDown.Value =  app.DatapointDropDown.Items{app.dataPointIndex - 1};
                app.dataPointIndex = app.dataPointIndex - 1;
                HeatMapButtonPushed(app, event);
            end
        end

        % Button pushed function: LocateSensorsButton
        function LocateSensorsButtonPushed(app, event)
            loadSet = app.loadedSet;
            app.locatorApp = SensorLocator(app, loadSet, app.DatasetDropDown.Value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.webcamObj)
                %closePreview(app.webcamObj);
                delete(app.webcamObj);
            end
            delete(app.locatorApp);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.WidthPointsEditFieldLabel = uilabel(app.UIFigure);
            app.WidthPointsEditFieldLabel.Text = 'Width Points';
            app.WidthPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthPointsEditField.Value = 3;
            app.LengthPointsEditFieldLabel = uilabel(app.UIFigure);
            app.LengthPointsEditFieldLabel.Text = 'Length Points';
            app.LengthPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthPointsEditField.Value = 10;
            app.LogDataButton = uibutton(app.UIFigure, 'push');
            app.LogDataButton.ButtonPushedFcn = createCallbackFcn(app, @LogDataButtonPushed, true);
            app.LogDataButton.Enable = 'off';
            app.LogDataButton.Text = 'Log Data';
            app.WidthinEditFieldLabel = uilabel(app.UIFigure);
            app.WidthinEditFieldLabel.Text = 'Width (in)';
            app.WidthinEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthinEditField.Value = 1;
            app.LengthinEditFieldLabel = uilabel(app.UIFigure);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthinEditField.Value = 6;
            app.SkinPatchDataCollectorLabel = uilabel(app.UIFigure);
            app.SkinPatchDataCollectorLabel.Text = 'Skin Patch Data Collector';
            app.DataPointTrackerLabel = uilabel(app.UIFigure);
            app.DataPointTrackerLabel.Text = 'DataPointTrackerLabel';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SetpointsandstartwebcamLabel = uilabel(app.UIFigure);
            app.SetpointsandstartwebcamLabel.Text = 'Set points and start webcam';
            app.SaveLocationEditFieldLabel = uilabel(app.UIFigure);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocationEditField = uieditfield(app.UIFigure, 'text');
            app.SaveLocationEditField.Value = 'Data';
            app.SampleSizeEditFieldLabel = uilabel(app.UIFigure);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleSizeEditField.Value = 50;
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionEditField.Value = 'Skin patch data';
            app.RXEditFieldLabel = uilabel(app.UIFigure);
            app.RXEditFieldLabel.Text = 'RX';
            app.RXEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXEditField.Value = 3;
            app.TXEditFieldLabel = uilabel(app.UIFigure);
            app.TXEditFieldLabel.Text = 'TX';
            app.TXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TXEditField.Value = 10;
            app.CalibrationSamplesEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationSamplesEditFieldLabel.Text = 'Calibration Samples';
            app.CalibrationSamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationSamplesEditField.Value = 50;
            app.FlipXCheckBox = uicheckbox(app.UIFigure);
            app.FlipXCheckBox.Text = 'Flip X';
            app.DataAnalyzerLabel = uilabel(app.UIFigure);
            app.DataAnalyzerLabel.Text = 'Data Analyzer';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.ClickedFcn = createCallbackFcn(app, @DatasetDropDownClicked, true);
            app.DatasetDropDown.Value = {};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DatapointDropDownLabel = uilabel(app.UIFigure);
            app.DatapointDropDownLabel.Enable = 'off';
            app.DatapointDropDownLabel.Text = 'Datapoint';
            app.DatapointDropDown = uidropdown(app.UIFigure);
            app.DatapointDropDown.Items = {'(x,y,#)'};
            app.DatapointDropDown.Enable = 'off';
            app.DatapointDropDown.Value = '(x,y,#)';
            app.HeatMapButton = uibutton(app.UIFigure, 'push');
            app.HeatMapButton.ButtonPushedFcn = createCallbackFcn(app, @HeatMapButtonPushed, true);
            app.HeatMapButton.Enable = 'off';
            app.HeatMapButton.Text = 'Heat Map';
            app.LocateSensorsButton = uibutton(app.UIFigure, 'push');
            app.LocateSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @LocateSensorsButtonPushed, true);
            app.LocateSensorsButton.Enable = 'off';
            app.LocateSensorsButton.Text = 'Locate Sensors';
            app.HeatMapPanel = uipanel(app.UIFigure);
            app.HeatMapPanel.Title = 'Heat Map';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next -->';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Enable = 'off';
            app.PrevButton.Text = '<-- Prev';
            app.BorderSliderLabel = uilabel(app.UIFigure);
            app.BorderSliderLabel.Text = 'Border';
            app.BorderSlider = uislider(app.UIFigure);
            app.BorderSlider.Limits = [0.001 1];
            app.BorderSlider.Value = 0.75;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataCollectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end