classdef SensorLocator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StandardDeviationofErrorcmEditField  matlab.ui.control.NumericEditField
        StandardDeviationofErrorcmEditFieldLabel  matlab.ui.control.Label
        FontsizeEditField             matlab.ui.control.NumericEditField
        FontsizeEditFieldLabel        matlab.ui.control.Label
        SingleCheckBox                matlab.ui.control.CheckBox
        EmptyCheckBox                 matlab.ui.control.CheckBox
        SaveNameEditField             matlab.ui.control.EditField
        SaveNameEditFieldLabel        matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        ErrorCompareButton            matlab.ui.control.Button
        ErrorProfilesListBox          matlab.ui.control.ListBox
        ErrorProfilesListBoxLabel     matlab.ui.control.Label
        EstimationMethodButtonGroup   matlab.ui.container.ButtonGroup
        MaxButton                     matlab.ui.control.RadioButton
        ContourButton                 matlab.ui.control.RadioButton
        ContourThresholdmaxEditField  matlab.ui.control.NumericEditField
        ContourThresholdmaxEditFieldLabel  matlab.ui.control.Label
        ContourCheckBox               matlab.ui.control.CheckBox
        ErrorListBox                  matlab.ui.control.ListBox
        ErrorListBoxLabel             matlab.ui.control.Label
        TrueDataDropDown              matlab.ui.control.DropDown
        TrueDataDropDownLabel         matlab.ui.control.Label
        PixelspercmEditField          matlab.ui.control.NumericEditField
        PixelspercmEditFieldLabel     matlab.ui.control.Label
        UpscaledDataPanel             matlab.ui.container.Panel
        RawDataPanel                  matlab.ui.container.Panel
        RunAllButton                  matlab.ui.control.Button
        SensorNumberDropDown          matlab.ui.control.DropDown
        SensorNumberDropDownLabel     matlab.ui.control.Label
        DesciptionViewerLabel         matlab.ui.control.Label
        DataNameViewerLabel           matlab.ui.control.Label
        SensorLocatorLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        collectApp; % Main App
        currentPointHeat; % Description
        loadSet; %Data
        unitsSet;
        unitsSet2;
        pixelsPerInch; %Used for the interpolated set
        h; %first heat map
        h2; %Second heat map
        trueData; %For plotting true sensor locations
        highlightSensor; %scatter plot item for identifying the sensor
        highlightContour; %Contour plot of highlighted object
        highlightContour2;
        borderLim;
        leg; %legend object
        face;

        %%%%%% Saving Results %%%%%%
        lastError;
    end
    
    methods (Access = private)
        
        
        function results = updatePanels(app)

            set(app.UIAxes, 'FontSize', app.FontsizeEditField.Value);

            %%%%%%%% Raw Data Heatmap %%%%%%%%%%%
            currP = 1;

            tSet = 1:app.loadSet.SkinDataSet.resLength;

            for t = tSet
                for r = 1:app.loadSet.SkinDataSet.resWidth
                    cData(r,t) = app.loadSet.SkinDataSet.dataRaw(currP).avgData(str2num(app.SensorNumberDropDown.Value));
                    currP = currP+1;
                end
            end
            app.h = heatmap(app.RawDataPanel, cData, 'GridVisible','off', 'Colormap', hot, 'CellLabelColor','none',...
                'YLabel', {'Log Number'; '(Vert.)'}, 'XLabel', "Log Number (Horz.)",'ColorbarVisible','off', ...
                'Title', "Sensor " + app.SensorNumberDropDown.Value + " Point Log Map", ...
                'FontSize', app.FontsizeEditField.Value);
            %app.RawDataPanel

            %%%%%%% Following square box code from Adam Danz %%%%%%%%%%
            % Originial Forum: https://www.mathworks.com/matlabcentral/answers/481666-heatmap-chart-depict-squares-rather-than-rectangles

            if app.unitsSet == 0
                % Temporarily change axis units 
                originalUnits = app.h.Units;  % save original units (probaly normalized)
                app.h.Units = 'centimeters';  % any unit that will result in squares
                % Get number of rows & columns
                sz = size(app.h.ColorData); 
                % Change axis size & position;
                originalPos = app.h.Position; 
                % make axes square (not the table cells, just the axes)
                app.h.Position(3:4) = min(app.h.Position(3:4))*[1,1]; 
                if sz(1)>sz(2)
                    % make the axis size more narrow and re-center
                    app.h.Position(3) = app.h.Position(3)*(sz(2)/sz(1)); 
                    app.h.Position(1) = (originalPos(1)+originalPos(3)/2)-(app.h.Position(3)/2);
                else
                    % make the axis size shorter and re-center
                    app.h.Position(4) = app.h.Position(4)*(sz(1)/sz(2));
                    app.h.Position(2) = (originalPos(2)+originalPos(4)/2)-(app.h.Position(4)/2);
                end
                % Return axis to original units
                app.h.Units = originalUnits; 
                app.unitsSet = 1;
            end

            %%%%%% Interpolated Data %%%%%%%%%
            F = scatteredInterpolant(app.loadSet.SkinDataSet.posLinAvgReal(1,:)'*2.54, app.loadSet.SkinDataSet.posLinAvgReal(2,:)'*2.54, app.loadSet.SkinDataSet.dataAvgsLin(str2num(app.SensorNumberDropDown.Value), :)');
            F.Method = 'natural';
            newX = (0:(1/app.PixelspercmEditField.Value):app.loadSet.SkinDataSet.lengthIn*2.54);
            newY = (app.loadSet.SkinDataSet.widthIn*2.54:-(1/app.PixelspercmEditField.Value):0);
            [X,Y] = meshgrid(newX, newY); %Make new locations to interpolate at
            xlin = X(:); %Flatten the matrix
            ylin = Y(:);
            DataInterp = F([xlin, ylin]); %evaluate interpolation

            %For testing interpolation
            %save("testing.mat", "DataInterp", "F", "newX", "newY", "X", "Y");

            %Organizing data into heat map
            currP = 1;
            for x = 1:length(newX)
                for y = 1:length(newY)
                    cDataInterp(y,x) = DataInterp(currP);
                    currP = currP + 1;
                end
            end
            app.h2 = heatmap(app.UpscaledDataPanel, cDataInterp, 'GridVisible','off', 'Colormap', hot, 'CellLabelColor','none', ...
                'YLabel', {'Width','(cm)'}, 'XLabel', "Length (cm)",'ColorbarVisible','off', ...
                'Title', {'Point Log Map', "Upscaled to "+app.PixelspercmEditField.Value^2 + " pixles/cm^2"}, ...
                'FontSize', app.FontsizeEditField.Value);

            %Labels on x and y axis
            labelsX = app.h2.XDisplayLabels;                                 
            app.h2.XDisplayLabels = repmat(' ',size(labelsX,1), size(labelsX,2));
            labelsY = app.h2.YDisplayLabels;                                 
            app.h2.YDisplayLabels = repmat(' ',size(labelsY,1), size(labelsY,2));

            endValueLen = app.loadSet.SkinDataSet.lengthIn*2.54;
            endValueWid = app.loadSet.SkinDataSet.widthIn*2.54;
            %app.h2.XDisplayLabels{end} = string(endValueLen);
            count = 1;
            while count < endValueLen
                app.h2.XDisplayLabels{ceil((count/endValueLen)*size(labelsX,1))} = count;
                count = count + 1;
            end
            
            count = 1;
            while count < endValueWid
                app.h2.YDisplayLabels{ceil(((endValueWid - count)/endValueWid)*size(labelsY,1))} = count;
                count = count + 1;
            end
            app.h2.XDisplayLabels{1} = "0"; 
            %app.h2.YDisplayLabels{1} = string(app.loadSet.SkinDataSet.widthIn*2.54);
            app.h2.YDisplayLabels{end} = "0";


            %%%%%%%%%%% Determining the sensor location %%%%%%%%%%
            if app.MaxButton.Value %Max
                [~, sensorPosIndex] = max(DataInterp);
                results = [X(sensorPosIndex), Y(sensorPosIndex)];
            elseif app.ContourButton.Value %Contour
                validX = xlin(DataInterp>=(app.ContourThresholdmaxEditField.Value*max(DataInterp)));
                validY = ylin(DataInterp>=(app.ContourThresholdmaxEditField.Value*max(DataInterp)));
                meanX = mean(validX);
                meanY = mean(validY);
                results = [meanX, meanY];
            else
                results = [0,0];
            end


            %Remove X and Y ticks
            %labels = app.h2.XDisplayLabels;                                 
            %app.h2.XDisplayLabels = repmat(' ',size(labels,1), size(labels,2));
            %labels = app.h2.YDisplayLabels;                                 
            %app.h2.YDisplayLabels = repmat(' ',size(labels,1), size(labels,2));
            
            %%%%%%% Following square box code from Adam Danz %%%%%%%%%%
            % Originial Forum: https://www.mathworks.com/matlabcentral/answers/481666-heatmap-chart-depict-squares-rather-than-rectangles

            if app.unitsSet2 == 0
                % Temporarily change axis units 
                originalUnits = app.h2.Units;  % save original units (probaly normalized)
                app.h2.Units = 'centimeters';  % any unit that will result in squares
                % Get number of rows & columns
                sz = size(app.h2.ColorData); 
                % Change axis size & position;
                originalPos = app.h2.Position; 
                % make axes square (not the table cells, just the axes)
                app.h2.Position(3:4) = min(app.h2.Position(3:4))*[1,1]; 
                if sz(1)>sz(2)
                    % make the axis size more narrow and re-center
                    app.h2.Position(3) = app.h2.Position(3)*(sz(2)/sz(1)); 
                    app.h2.Position(1) = (originalPos(1)+originalPos(3)/2)-(app.h2.Position(3)/2);
                else
                    % make the axis size shorter and re-center
                    app.h2.Position(4) = app.h2.Position(4)*(sz(1)/sz(2));
                    app.h2.Position(2) = (originalPos(2)+originalPos(4)/2)-(app.h2.Position(4)/2);
                end
                % Return axis to original units
                app.h2.Units = originalUnits; 
                app.unitsSet2 = 1;
            end


            %%%%%% Countour Plot %%%%%%%%
            if app.ContourCheckBox.Value
                hold(app.UIAxes, "on");
                if ~isempty(app.highlightContour)
                    delete(app.highlightContour);
                    delete(app.highlightContour2);
                end
                cm = hot(100);
                colormap(app.UIAxes, cm(20:80, :));
                numLines = 8;

                %Normalized Data
                cDataInterp2 = cDataInterp./max(DataInterp);

                [c2,app.highlightContour2] = contour(app.UIAxes, X, Y, cDataInterp2, [app.ContourThresholdmaxEditField.Value, app.ContourThresholdmaxEditField.Value], 'LineStyle', '--','LineWidth', 1);
                clabel(c2, app.highlightContour2);
                [c,app.highlightContour] = contourf(app.UIAxes, X, Y, cDataInterp, numLines, 'LineColor', 'none','LineWidth', 0.5);
%                 if app.SingleCheckBox.Value
%                     [c,app.highlightContour] = contourf(app.UIAxes, X, Y, cDataInterp, numLines, '--', 'LineWidth', 0.5);
%                 else
%                     [c,app.highlightContour] = contourf(app.UIAxes, X, Y, cDataInterp, numLines, 'LineColor', 'none','LineWidth', 0.5);
%                 end
                app.highlightContour.FaceAlpha = 0.7;
                
                %Plot organizing
                f=get(app.UIAxes,'Children');
                app.leg = legend(app.UIAxes, [f(10),f(8)],'Predicted Location','Actual Location');
                save("testing.mat", "f");
                set(app.UIAxes, 'Children', flipud(f));
                %app.face = fill(app.UIAxes, [z1(1), z2(1), z3(1), z4(1)], [z1(2), z2(2), z3(2), z4(2)], 'k', 'FaceAlpha', 0);
    
                axis(app.UIAxes, 'equal');
                ylim(app.UIAxes, [-app.borderLim, app.loadSet.SkinDataSet.widthIn*2.54 + app.borderLim]);
                xlim(app.UIAxes, [-app.borderLim, app.loadSet.SkinDataSet.lengthIn*2.54 + app.borderLim]);
                hold(app.UIAxes, "off");

                if app.SingleCheckBox.Value, clabel(c,app.highlightContour); end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, dataSet, dataName)

            app.currentPointHeat = 1;
            app.collectApp = caller;
            app.loadSet = dataSet;
            app.DataNameViewerLabel.Text = dataName;
            app.DesciptionViewerLabel.Text = "Description: " + dataSet.SkinDataSet.desc;
            app.RunAllButton.Enable = "on";
            app.ErrorCompareButton.Enable = "on";
            app.SensorNumberDropDown.Items = cellstr(string(1:dataSet.SkinDataSet.sensNum));
            app.unitsSet = 0; %Units for the heatmap only need to be set once
            app.unitsSet2 = 0; %Units for the heatmap only need to be set once
            app.borderLim = 1;
            app.SaveNameEditField.Value = dataName(1:end-4) + "_Figure";

            %Normalize
            for i = 1:app.loadSet.SkinDataSet.sensNum
                app.loadSet.SkinDataSet.dataAvgsLin(i,:) = app.loadSet.SkinDataSet.dataAvgsLin(i,:) - min(app.loadSet.SkinDataSet.dataAvgsLin(i,:));
            end

            updatePanels(app);
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            %updatePanels(app);
            delete(app.leg); %cleanup
            app.ContourCheckBox.Value = 0;
            %%%% Check if true data was selected %%%%
            runTrue = 0;
            if app.TrueDataDropDown.Value ~= ""
                runTrue  = 1;
                app.trueData = load("SkinTruePositions\" + app.TrueDataDropDown.Value);
            end

            %%%% Plot position for each sensor on large graph %%%%%
            z1 = [0, app.loadSet.SkinDataSet.widthIn*2.54];
            z2 = [app.loadSet.SkinDataSet.lengthIn*2.54, app.loadSet.SkinDataSet.widthIn*2.54];
            z3 = [app.loadSet.SkinDataSet.lengthIn*2.54, 0];
            z4 = [0,0];

           
            plot(app.UIAxes, [z1(1),z2(1)], [z1(2),z2(2)], 'k', 'LineWidth',4);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [z2(1),z3(1)], [z2(2),z3(2)], 'k', 'LineWidth',4);
            plot(app.UIAxes, [z3(1),z4(1)], [z3(2),z4(2)], 'k', 'LineWidth',4);
            plot(app.UIAxes, [z4(1),z1(1)], [z4(2),z1(2)], 'k', 'LineWidth',4);
            app.highlightContour = fill(app.UIAxes, [z1(1), z2(1), z3(1), z4(1)], [z1(2), z2(2), z3(2), z4(2)], 'b', 'FaceAlpha', 0.3);

            axis(app.UIAxes, 'equal');
            ylim(app.UIAxes, [-app.borderLim, app.loadSet.SkinDataSet.widthIn*2.54 + app.borderLim]);
            xlim(app.UIAxes, [-app.borderLim, app.loadSet.SkinDataSet.lengthIn*2.54 + app.borderLim]);

            %Plot each location
            itemSet = cellstr(strings(app.loadSet.SkinDataSet.sensNum,1));
            app.ErrorListBox.Enable = "off";
            if app.SingleCheckBox.Value
                title(app.UIAxes, "Sensor " + app.SensorNumberDropDown.Value + " Location in Skin Patch B");
                sensorPos = updatePanels(app);
                i = str2num(app.SensorNumberDropDown.Value);
                if runTrue
                    tp = scatter(app.UIAxes, app.trueData.trueSet.posReal(1,i), app.trueData.trueSet.posReal(2,i), 100, 'k', 'x', 'LineWidth',1.5);
                    if ~app.EmptyCheckBox.Value, plot(app.UIAxes, [sensorPos(1), app.trueData.trueSet.posReal(1,i)], [sensorPos(2),app.trueData.trueSet.posReal(2,i)], 'r--'); end
                    difference = [abs(sensorPos(1) - app.trueData.trueSet.posReal(1,i)), abs(sensorPos(2) - app.trueData.trueSet.posReal(2,i))];
                    normError = norm(difference);
                    itemSet{i} = convertStringsToChars("Sensor " + i + ": " + normError + " cm");
                    app.ErrorListBox.Items = itemSet;
                end
                if ~app.EmptyCheckBox.Value, pp = scatter(app.UIAxes, sensorPos(1), sensorPos(2), 50, 'green', 'filled' , 'MarkerEdgeColor', 'k', 'LineWidth',0.5); end
            else
                title(app.UIAxes, "Sensor Locations in Skin Patch A");
                for i = 1:app.loadSet.SkinDataSet.sensNum
                    app.SensorNumberDropDown.Value = app.SensorNumberDropDown.Items{i};
                    sensorPos(:,i) = updatePanels(app)';
                    if runTrue
                        tp = scatter(app.UIAxes, app.trueData.trueSet.posReal(1,i), app.trueData.trueSet.posReal(2,i), 50, 'k', 'x', 'LineWidth',1.5);
                        if ~app.EmptyCheckBox.Value, plot(app.UIAxes, [sensorPos(1,i), app.trueData.trueSet.posReal(1,i)], [sensorPos(2,i),app.trueData.trueSet.posReal(2,i)], 'r--'); end
                        difference = [abs(sensorPos(1,i) - app.trueData.trueSet.posReal(1,i)), abs(sensorPos(2,i) - app.trueData.trueSet.posReal(2,i))]; %Difference in position
                        normError(i) = norm(difference); %direct distance in position
                        itemSet{i} = convertStringsToChars("Sensor " + i + ": " + normError(i) + " cm");
                        app.ErrorListBox.Items = itemSet;
                    end
                    if ~app.EmptyCheckBox.Value, pp = scatter(app.UIAxes, sensorPos(1,i), sensorPos(2,i), 50, 'green', 'filled' , 'MarkerEdgeColor', 'k', 'LineWidth',0.5); end
                    pause(0.01);
                end
                app.loadSet.SkinDataSet.posPred = sensorPos;
            end
            f=get(app.UIAxes,'Children');
            if app.EmptyCheckBox.Value
                app.leg = legend(app.UIAxes, tp, 'Sensor True Position');
            else
                app.leg = legend(app.UIAxes, [pp,tp],'Predicted Location','Actual Location');
            end
            set(app.UIAxes, 'FontSize', app.FontsizeEditField.Value);

            axis(app.UIAxes, 'equal');
            ylim(app.UIAxes, [-app.borderLim, app.loadSet.SkinDataSet.widthIn*2.54 + app.borderLim]);
            xlim(app.UIAxes, [-app.borderLim, app.loadSet.SkinDataSet.lengthIn*2.54 + app.borderLim]);


            app.ErrorListBox.Enable = "on";
            app.ContourCheckBox.Enable = "on";
            app.ContourCheckBox.Value = 1;

            % Add to error profiler
            app.loadSet.SkinDataSet.sensorError = normError;
            app.lastError = sqrt((1/(length(normError)-1))*sum(normError.^2));
            if app.ContourButton.Value
                app.ErrorProfilesListBox.Items{end+1} = convertStringsToChars("Contour, ppcm=" + app.PixelspercmEditField.Value + ", error=" + app.lastError + ", %max=" + app.ContourThresholdmaxEditField.Value);
            elseif app.MaxButton.Value
                app.ErrorProfilesListBox.Items{end+1} = convertStringsToChars("Max est, ppcm=" + app.PixelspercmEditField.Value + ", error=" + app.lastError);
            end
            assignin("base", "lastError", app.lastError);
            assignin("base", "lastRunSkinData", app.loadSet.SkinDataSet);
            app.StandardDeviationofErrorcmEditField.Value = app.lastError;

            hold(app.UIAxes, 'off');
        end

        % Value changed function: SensorNumberDropDown
        function SensorNumberDropDownValueChanged(app, event)
            value = app.SensorNumberDropDown.Value;
            updatePanels(app);
        end

        % Clicked callback: TrueDataDropDown
        function TrueDataDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            listings = dir("SkinTruePositions");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.TrueDataDropDown.Items = itemSet;
        end

        % Value changed function: ErrorListBox
        function ErrorListBoxValueChanged(app, event)
            value = app.ErrorListBox.Value;
            [~, indices] = ismember(value, app.ErrorListBox.Items);

            %Call the highlighter to change
            hold(app.UIAxes, 'on');
            if ~isempty(app.highlightSensor)
                delete(app.highlightSensor);
            end
            app.highlightSensor = scatter(app.UIAxes, app.trueData.trueSet.posReal(1,indices), app.trueData.trueSet.posReal(2,indices), 500, 'blue', 'x', 'filled');    
            hold(app.UIAxes, 'off');

            %Call the heatmaps to change
            app.SensorNumberDropDown.Value = app.SensorNumberDropDown.Items{indices};
            updatePanels(app);
            set(app.UIAxes, 'FontSize', app.FontsizeEditField.Value * 1.5);

            %Plot organizing
%             f=get(app.UIAxes,'Children');
%             legend(app.UIAxes, [f(6),f(5)],'Predicted Location','Actual Location');
%             ylim(app.UIAxes, [-app.borderLim, app.loadSet.SkinDataSet.widthIn + app.borderLim]);
%             xlim(app.UIAxes, [-app.borderLim, app.loadSet.SkinDataSet.lengthIn + app.borderLim]);
%             axis(app.UIAxes, 'equal');
        end

        % Button pushed function: ErrorCompareButton
        function ErrorCompareButtonPushed(app, event)
            startdex = 1;
            if exist("Data\" + app.DataNameViewerLabel.Text(1:end-4) + "ErrorCompare.mat", "file")
                loadErr = load("Data\" + app.DataNameViewerLabel.Text(1:end-4) + "ErrorCompare.mat");
                errorObj = loadErr.errorObj;
                startdex = length(errorObj.error(:,1));
            end
            for j = startdex:8
                app.PixelspercmEditField.Value = 2^(j-1);
                for i = 1:6
                    app.ContourCheckBox.Value = 0;
                    app.ContourThresholdmaxEditField.Value = 0.5 + 0.05*i;
                    RunAllButtonPushed(app, event);

                    errorObj.error(j, i) = app.lastError;
                    errorObj.ppi(j, i) = app.PixelspercmEditField.Value;
                    errorObj.threshold(j, i) = 0.5 + 0.05*i;

                    exportgraphics(app.UIAxes, "Figures\" + convertCharsToStrings(app.ErrorProfilesListBox.Items{end}) + ".jpg");
                end
                errorObj.list = app.ErrorProfilesListBox.Items;
                save("Data\" + app.DataNameViewerLabel.Text(1:end-4) + "ErrorCompare.mat", "errorObj");
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dirLocation = uigetdir;
            fileLocation = fullfile(dirLocation);

            %app.SaveNameEditField.Value = string(fileLocation) +"\"+ string(app.SaveNameEditField.Value) + "_3.jpg";
            % Sensor Location Graph
            exportgraphics(app.UIAxes, string(fileLocation) +"\"+ string(app.SaveNameEditField.Value) + "_3.jpg");

            %Raw Data Heatmap
            exportgraphics(app.h, string(fileLocation) +"\"+ string(app.SaveNameEditField.Value) + "_1.jpg");

            %Upscaled Image Heatmap
            exportgraphics(app.h2, string(fileLocation) +"\"+ string(app.SaveNameEditField.Value) + "_2.jpg", 'Resolution', 400);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sensor Locations in Skin Patch')
            xlabel(app.UIAxes, 'Length (cm)')
            ylabel(app.UIAxes, 'Width (cm)')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.SensorLocatorLabel = uilabel(app.UIFigure);
            app.SensorLocatorLabel.Text = 'Sensor Locator';
            app.DataNameViewerLabel = uilabel(app.UIFigure);
            app.DataNameViewerLabel.Text = 'Data Name Viewer';
            app.DesciptionViewerLabel = uilabel(app.UIFigure);
            app.DesciptionViewerLabel.Text = 'Desciption Viewer';
            app.SensorNumberDropDownLabel = uilabel(app.UIFigure);
            app.SensorNumberDropDownLabel.Text = 'Sensor Number';
            app.SensorNumberDropDown = uidropdown(app.UIFigure);
            app.SensorNumberDropDown.Items = {};
            app.SensorNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorNumberDropDownValueChanged, true);
            app.SensorNumberDropDown.Value = {};
            app.RunAllButton = uibutton(app.UIFigure, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Enable = 'off';
            app.RunAllButton.Text = 'Run All';
            app.RawDataPanel = uipanel(app.UIFigure);
            app.RawDataPanel.Title = 'Raw Data';
            app.UpscaledDataPanel = uipanel(app.UIFigure);
            app.UpscaledDataPanel.Title = 'Upscaled Data';
            app.PixelspercmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelspercmEditFieldLabel.Text = 'Pixels per cm';
            app.PixelspercmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelspercmEditField.Value = 10;
            app.TrueDataDropDownLabel = uilabel(app.UIFigure);
            app.TrueDataDropDownLabel.Text = 'True Data';
            app.TrueDataDropDown = uidropdown(app.UIFigure);
            app.TrueDataDropDown.Items = {};
            app.TrueDataDropDown.ClickedFcn = createCallbackFcn(app, @TrueDataDropDownClicked, true);
            app.TrueDataDropDown.Value = {};
            app.ErrorListBoxLabel = uilabel(app.UIFigure);
            app.ErrorListBoxLabel.Text = 'Error';
            app.ErrorListBox = uilistbox(app.UIFigure);
            app.ErrorListBox.Items = {};
            app.ErrorListBox.ValueChangedFcn = createCallbackFcn(app, @ErrorListBoxValueChanged, true);
            app.ErrorListBox.Value = {};
            app.ContourCheckBox = uicheckbox(app.UIFigure);
            app.ContourCheckBox.Enable = 'off';
            app.ContourCheckBox.Text = 'Contour';
            app.ContourThresholdmaxEditFieldLabel = uilabel(app.UIFigure);
            app.ContourThresholdmaxEditFieldLabel.Text = 'Contour Threshold (%max)';
            app.ContourThresholdmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourThresholdmaxEditField.Value = 0.65;
            app.EstimationMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.EstimationMethodButtonGroup.Title = 'Estimation Method';
            app.ContourButton = uiradiobutton(app.EstimationMethodButtonGroup);
            app.ContourButton.Text = 'Contour';
            app.ContourButton.Value = true;
            app.MaxButton = uiradiobutton(app.EstimationMethodButtonGroup);
            app.MaxButton.Text = 'Max';
            app.ErrorProfilesListBoxLabel = uilabel(app.UIFigure);
            app.ErrorProfilesListBoxLabel.Text = 'Error Profiles';
            app.ErrorProfilesListBox = uilistbox(app.UIFigure);
            app.ErrorProfilesListBox.Items = {};
            app.ErrorProfilesListBox.Value = {};
            app.ErrorCompareButton = uibutton(app.UIFigure, 'push');
            app.ErrorCompareButton.ButtonPushedFcn = createCallbackFcn(app, @ErrorCompareButtonPushed, true);
            app.ErrorCompareButton.Enable = 'off';
            app.ErrorCompareButton.Text = 'Error Compare';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveNameEditFieldLabel = uilabel(app.UIFigure);
            app.SaveNameEditFieldLabel.Text = 'Save Name';
            app.SaveNameEditField = uieditfield(app.UIFigure, 'text');
            app.EmptyCheckBox = uicheckbox(app.UIFigure);
            app.EmptyCheckBox.Text = 'Empty';
            app.SingleCheckBox = uicheckbox(app.UIFigure);
            app.SingleCheckBox.Text = 'Single';
            app.FontsizeEditFieldLabel = uilabel(app.UIFigure);
            app.FontsizeEditFieldLabel.Text = 'Font size';
            app.FontsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FontsizeEditField.Value = 10;
            app.StandardDeviationofErrorcmEditFieldLabel = uilabel(app.UIFigure);
            app.StandardDeviationofErrorcmEditFieldLabel.Text = 'Standard Deviation of Error (cm)';
            app.StandardDeviationofErrorcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.StandardDeviationofErrorcmEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SensorLocator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end